classdef CFST_AxialCapacityPrediction < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GUI                             matlab.ui.Figure
        Label                           matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        InputvariablesPanel             matlab.ui.container.Panel
        CrosssectionalshapeDropDownLabel  matlab.ui.control.Label
        CrosssectionalshapeDropDown     matlab.ui.control.DropDown
        Crosssectionalareaofsteeltubemm2Label  matlab.ui.control.Label
        Crosssectionalareaofsteeltubemm2EditField  matlab.ui.control.NumericEditField
        Crosssectionalareaofconcretecoremm2EditFieldLabel  matlab.ui.control.Label
        Crosssectionalareaofconcretecoremm2EditField  matlab.ui.control.NumericEditField
        YieldstrengthofsteeltubefyMPaEditField_2Label  matlab.ui.control.Label
        YieldstrengthofsteeltubefyMPaEditField_2  matlab.ui.control.NumericEditField
        CompressivestrengthofconcretefcMPaEditField_2Label  matlab.ui.control.Label
        CompressivestrengthofconcretefcMPaEditField_2  matlab.ui.control.NumericEditField
        LengthofcolumnmmEditFieldLabel  matlab.ui.control.Label
        LengthofcolumnmmEditField       matlab.ui.control.NumericEditField
        OutputPanel                     matlab.ui.container.Panel
        AxialcapacityofCFSTcolumnkNEditFieldLabel  matlab.ui.control.Label
        AxialcapacityofCFSTcolumnkNEditField  matlab.ui.control.NumericEditField
        StartpredictButton              matlab.ui.control.Button
        RangeLabel                      matlab.ui.control.Label
        RangeDensityPolymerSlider       matlab.ui.control.Slider
        Label_4                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        RangeSlider_2Label              matlab.ui.control.Label
        RangeYoungPolymerSlider         matlab.ui.control.Slider
        RangeSlider_2Label_2            matlab.ui.control.Label
        RangeTensilePolymerSlider       matlab.ui.control.Slider
        RangeSlider_2Label_3            matlab.ui.control.Label
        RangeWeightFractionSlider       matlab.ui.control.Slider
        RangeSlider_2Label_4            matlab.ui.control.Label
        RangeCNTDensitySlider           matlab.ui.control.Slider
        Label_5                         matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        Label_9                         matlab.ui.control.Label
        Label_11                        matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        Label_8                         matlab.ui.control.Label
        Label_10                        matlab.ui.control.Label
        Label_12                        matlab.ui.control.Label
        MinLabel                        matlab.ui.control.Label
        MaxLabel                        matlab.ui.control.Label
        Button                          matlab.ui.control.Button
    end

    methods (Access = private)

        % Value changed function: RangeDensityPolymerSlider
        function RangeDensityPolymerSliderValueChanging(app, event)
            latestvalue = event.Value; % Current value
            app.Crosssectionalareaofsteeltubemm2EditField.Value = latestvalue;  % Update value
        end

        % Value changed function: 
        % Crosssectionalareaofsteeltubemm2EditField
        function Crosssectionalareaofsteeltubemm2EditFieldValueChanged(app, event)
            latestvalue = event.Value; % Current value
            app.RangeDensityPolymerSlider.Value = latestvalue;  % Update value           
        end

        % Value changed function: 
        % Crosssectionalareaofconcretecoremm2EditField
        function Crosssectionalareaofconcretecoremm2EditFieldValueChanged(app, event)
            latestvalue = event.Value; % Current value
            app.RangeYoungPolymerSlider.Value = latestvalue;  % Update value
        end

        % Value changed function: RangeYoungPolymerSlider
        function RangeYoungPolymerSliderValueChanged(app, event)
            latestvalue = event.Value; % Current value
            app.Crosssectionalareaofconcretecoremm2EditField.Value = latestvalue;  % Update value
        end

        % Value changed function: 
        % YieldstrengthofsteeltubefyMPaEditField_2
        function YieldstrengthofsteeltubefyMPaEditField_2ValueChanged(app, event)
            latestvalue = event.Value; % Current value
            app.RangeTensilePolymerSlider.Value = latestvalue;  % Update value
        end

        % Value changed function: RangeTensilePolymerSlider
        function RangeTensilePolymerSliderValueChanged(app, event)
            latestvalue = event.Value; % Current value
            app.YieldstrengthofsteeltubefyMPaEditField_2.Value = latestvalue;  % Update value
        end

        % Value changed function: 
        % CompressivestrengthofconcretefcMPaEditField_2
        function CompressivestrengthofconcretefcMPaEditField_2ValueChanged(app, event)
            latestvalue = event.Value; % Current value
            app.RangeWeightFractionSlider.Value = latestvalue;  % Update value
        end

        % Value changed function: RangeWeightFractionSlider
        function RangeWeightFractionSliderValueChanged(app, event)
            latestvalue = event.Value; % Current value
            app.CompressivestrengthofconcretefcMPaEditField_2.Value = latestvalue;  % Update value
        end

        % Value changed function: LengthofcolumnmmEditField
        function LengthofcolumnmmEditFieldValueChanged(app, event)
            latestvalue = event.Value; % Current value
            app.RangeCNTDensitySlider.Value = latestvalue;  % Update value
        end

        % Value changed function: RangeCNTDensitySlider
        function RangeCNTDensitySliderValueChanged(app, event)
            latestvalue = event.Value; % Current value
            app.LengthofcolumnmmEditField.Value = latestvalue;  % Update value
        end

        % Callback function
        function CNTaveragediameternmEditFieldValueChanged(app, event)
            latestvalue = event.Value; % Current value
            app.RangeCNTDiameterSlider.Value = latestvalue;  % Update value
        end

        % Callback function
        function RangeCNTDiameterSliderValueChanged(app, event)
            latestvalue = event.Value; % Current value
            app.CNTaveragediameternmEditField.Value = latestvalue;  % Update value
        end

        % Callback function
        function CNTaveragelengthnmEditFieldValueChanged(app, event)
            latestvalue = event.Value; % Current value
            app.RangeCNTLengthSlider.Value = latestvalue;  % Update value
        end

        % Callback function
        function RangeCNTLengthSliderValueChanged(app, event)
            latestvalue = event.Value; % Current value
            app.CNTaveragelengthnmEditField.Value = latestvalue;  % Update value
        end

        % Callback function
        function YoungsmodulusofCNTGPaEditFieldValueChanged(app, event)
            latestvalue = event.Value; % Current value
            app.RangeCNTYoungSlider.Value = latestvalue;  % Update value
        end

        % Callback function
        function RangeCNTYoungSliderValueChanged(app, event)
            latestvalue = event.Value; % Current value
            app.YoungsmodulusofCNTGPaEditField.Value = latestvalue;  % Update value
        end

        % Button pushed function: StartpredictButton
        function StartpredictButtonPushed(app, event)
            clc
            warning('off', 'all')
            
            if strcmp(app.CrosssectionalshapeDropDown.Value, 'Circular') == 1
                CrossSectionShape = 1;
            elseif strcmp(app.CrosssectionalshapeDropDown.Value, 'Elliptical') == 1
                CrossSectionShape = 2;
            elseif strcmp(app.CrosssectionalshapeDropDown.Value, 'Square') == 1
                CrossSectionShape = 3;
            elseif strcmp(app.CrosssectionalshapeDropDown.Value, 'Rectangular') == 1
                CrossSectionShape = 4;
            end
            PolymerDensity = app.Crosssectionalareaofsteeltubemm2EditField.Value;
            PolymerYoung = app.Crosssectionalareaofconcretecoremm2EditField.Value;
            PolymerTensileStrength = app.YieldstrengthofsteeltubefyMPaEditField_2.Value;
            weightFraction = app.CompressivestrengthofconcretefcMPaEditField_2.Value;
            CNTDensity = app.LengthofcolumnmmEditField.Value;
        
            Inputs = [PolymerDensity PolymerYoung PolymerTensileStrength ...
                weightFraction CNTDensity CrossSectionShape];
            
            pwd;
            currentFolder = pwd;
            %disp(currentFolder)
            cd(currentFolder)

            try
                load([currentFolder, '\trainedModel.mat'])
            catch exception
                fmsgbox = msgbox({'Please check the working directory!'; 'Operation stopped.'});
                return
            end
            try
                latestvalue = trainedModel.predictFcn(Inputs);
                disp(latestvalue)
                if latestvalue <= 0
                    fmsgbox = msgbox({'Lack of data for this configuration!'; 'Operation stopped.'});
                else
                    app.AxialcapacityofCFSTcolumnkNEditField.Value = latestvalue;  % Update value
                end
            catch exception
                fmsgbox = msgbox({'Lack of data for this configuration!'; 'Operation stopped.'});
                return
            end
            
            
            %if exist('GPR.mat') ~= 2; delete(fmsgbox); end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GUI = uifigure;
            app.GUI.Name = 'UI Figure';
            app.Label = uilabel(app.GUI);
            app.Label.Text = {'Graphical User Interface (GUI) for predicting axial capacity of concrete-filled steel tubular '; '(CFST) columns based on machine-learning model'};
            app.Label_2 = uilabel(app.GUI);
            app.Label_2.Text = {'This GUI was developed by Dr. Tien-Thinh Le, Duy Tan University, Da Nang, Vietnam'; 'Email: tienthinhle.vn@gmail.com; letienthinh@duytan.edu.vn, Created with Matlab 2018a'};
            app.InputvariablesPanel = uipanel(app.GUI);
            app.InputvariablesPanel.Title = 'Input variables';
            app.CrosssectionalshapeDropDownLabel = uilabel(app.InputvariablesPanel);
            app.CrosssectionalshapeDropDownLabel.Text = 'Cross-sectional shape';
            app.CrosssectionalshapeDropDown = uidropdown(app.InputvariablesPanel);
            app.CrosssectionalshapeDropDown.Items = {'Circular', 'Elliptical', 'Square', 'Rectangular'};
            app.CrosssectionalshapeDropDown.Value = 'Circular';
            app.Crosssectionalareaofsteeltubemm2Label = uilabel(app.GUI);
            app.Crosssectionalareaofsteeltubemm2Label.Text = 'Cross-sectional area of steel tube (mm^2)';
            app.Crosssectionalareaofsteeltubemm2EditField = uieditfield(app.GUI, 'numeric');
            app.Crosssectionalareaofsteeltubemm2EditField.Limits = [68.46 19104];
            app.Crosssectionalareaofsteeltubemm2EditField.ValueChangedFcn = createCallbackFcn(app, @Crosssectionalareaofsteeltubemm2EditFieldValueChanged, true);
            app.Crosssectionalareaofsteeltubemm2EditField.Value = 2284.12;
            app.Crosssectionalareaofconcretecoremm2EditFieldLabel = uilabel(app.GUI);
            app.Crosssectionalareaofconcretecoremm2EditFieldLabel.Text = 'Cross-sectional area of concrete core (mm^2)';
            app.Crosssectionalareaofconcretecoremm2EditField = uieditfield(app.GUI, 'numeric');
            app.Crosssectionalareaofconcretecoremm2EditField.Limits = [438.25 154886.05];
            app.Crosssectionalareaofconcretecoremm2EditField.ValueChangedFcn = createCallbackFcn(app, @Crosssectionalareaofconcretecoremm2EditFieldValueChanged, true);
            app.Crosssectionalareaofconcretecoremm2EditField.Value = 18945.27;
            app.YieldstrengthofsteeltubefyMPaEditField_2Label = uilabel(app.GUI);
            app.YieldstrengthofsteeltubefyMPaEditField_2Label.Text = 'Yield strength of steel tube fy (MPa)';
            app.YieldstrengthofsteeltubefyMPaEditField_2 = uieditfield(app.GUI, 'numeric');
            app.YieldstrengthofsteeltubefyMPaEditField_2.Limits = [185.7 853];
            app.YieldstrengthofsteeltubefyMPaEditField_2.ValueChangedFcn = createCallbackFcn(app, @YieldstrengthofsteeltubefyMPaEditField_2ValueChanged, true);
            app.YieldstrengthofsteeltubefyMPaEditField_2.Value = 400.12;
            app.CompressivestrengthofconcretefcMPaEditField_2Label = uilabel(app.GUI);
            app.CompressivestrengthofconcretefcMPaEditField_2Label.Text = 'Compressive strength of concrete f''c (MPa)';
            app.CompressivestrengthofconcretefcMPaEditField_2 = uieditfield(app.GUI, 'numeric');
            app.CompressivestrengthofconcretefcMPaEditField_2.Limits = [7.9 186];
            app.CompressivestrengthofconcretefcMPaEditField_2.ValueChangedFcn = createCallbackFcn(app, @CompressivestrengthofconcretefcMPaEditField_2ValueChanged, true);
            app.CompressivestrengthofconcretefcMPaEditField_2.Value = 50.42;
            app.LengthofcolumnmmEditFieldLabel = uilabel(app.GUI);
            app.LengthofcolumnmmEditFieldLabel.Text = 'Length of column (mm)';
            app.LengthofcolumnmmEditField = uieditfield(app.GUI, 'numeric');
            app.LengthofcolumnmmEditField.Limits = [100 5400];
            app.LengthofcolumnmmEditField.ValueChangedFcn = createCallbackFcn(app, @LengthofcolumnmmEditFieldValueChanged, true);
            app.LengthofcolumnmmEditField.Value = 1124.13;
            app.OutputPanel = uipanel(app.GUI);
            app.OutputPanel.Title = 'Output';
            app.AxialcapacityofCFSTcolumnkNEditFieldLabel = uilabel(app.OutputPanel);
            app.AxialcapacityofCFSTcolumnkNEditFieldLabel.Text = 'Axial capacity of CFST column (kN)';
            app.AxialcapacityofCFSTcolumnkNEditField = uieditfield(app.OutputPanel, 'numeric');
            app.StartpredictButton = uibutton(app.GUI, 'push');
            app.StartpredictButton.ButtonPushedFcn = createCallbackFcn(app, @StartpredictButtonPushed, true);
            app.StartpredictButton.Text = 'Start predict';
            app.RangeLabel = uilabel(app.GUI);
            app.RangeLabel.Text = 'Range:';
            app.RangeDensityPolymerSlider = uislider(app.GUI);
            app.RangeDensityPolymerSlider.Limits = [68.46 19104];
            app.RangeDensityPolymerSlider.MajorTicks = [];
            app.RangeDensityPolymerSlider.MajorTickLabels = {};
            app.RangeDensityPolymerSlider.ValueChangedFcn = createCallbackFcn(app, @RangeDensityPolymerSliderValueChanging, true);
            app.RangeDensityPolymerSlider.MinorTicks = [];
            app.RangeDensityPolymerSlider.Value = 2284.12;
            app.Label_4 = uilabel(app.GUI);
            app.Label_4.Text = '19104';
            app.Label_3 = uilabel(app.GUI);
            app.Label_3.Text = '68.46';
            app.RangeSlider_2Label = uilabel(app.GUI);
            app.RangeSlider_2Label.Text = 'Range:';
            app.RangeYoungPolymerSlider = uislider(app.GUI);
            app.RangeYoungPolymerSlider.Limits = [438.25 154886.05];
            app.RangeYoungPolymerSlider.MajorTicks = [];
            app.RangeYoungPolymerSlider.MajorTickLabels = {};
            app.RangeYoungPolymerSlider.ValueChangedFcn = createCallbackFcn(app, @RangeYoungPolymerSliderValueChanged, true);
            app.RangeYoungPolymerSlider.MinorTicks = [];
            app.RangeYoungPolymerSlider.Value = 18945.27;
            app.RangeSlider_2Label_2 = uilabel(app.GUI);
            app.RangeSlider_2Label_2.Text = 'Range:';
            app.RangeTensilePolymerSlider = uislider(app.GUI);
            app.RangeTensilePolymerSlider.Limits = [185.7 853];
            app.RangeTensilePolymerSlider.MajorTicks = [];
            app.RangeTensilePolymerSlider.MajorTickLabels = {};
            app.RangeTensilePolymerSlider.ValueChangedFcn = createCallbackFcn(app, @RangeTensilePolymerSliderValueChanged, true);
            app.RangeTensilePolymerSlider.MinorTicks = [];
            app.RangeTensilePolymerSlider.Value = 400.12;
            app.RangeSlider_2Label_3 = uilabel(app.GUI);
            app.RangeSlider_2Label_3.Text = 'Range:';
            app.RangeWeightFractionSlider = uislider(app.GUI);
            app.RangeWeightFractionSlider.Limits = [7.9 186];
            app.RangeWeightFractionSlider.MajorTicks = [];
            app.RangeWeightFractionSlider.MajorTickLabels = {};
            app.RangeWeightFractionSlider.ValueChangedFcn = createCallbackFcn(app, @RangeWeightFractionSliderValueChanged, true);
            app.RangeWeightFractionSlider.MinorTicks = [];
            app.RangeWeightFractionSlider.Value = 50.42;
            app.RangeSlider_2Label_4 = uilabel(app.GUI);
            app.RangeSlider_2Label_4.Text = 'Range:';
            app.RangeCNTDensitySlider = uislider(app.GUI);
            app.RangeCNTDensitySlider.Limits = [100 5400];
            app.RangeCNTDensitySlider.MajorTicks = [];
            app.RangeCNTDensitySlider.MajorTickLabels = {};
            app.RangeCNTDensitySlider.ValueChangedFcn = createCallbackFcn(app, @RangeCNTDensitySliderValueChanged, true);
            app.RangeCNTDensitySlider.MinorTicks = [];
            app.RangeCNTDensitySlider.Value = 1124.13;
            app.Label_5 = uilabel(app.GUI);
            app.Label_5.Text = '438.25';
            app.Label_7 = uilabel(app.GUI);
            app.Label_7.Text = '185.70';
            app.Label_9 = uilabel(app.GUI);
            app.Label_9.Text = '7.9';
            app.Label_11 = uilabel(app.GUI);
            app.Label_11.Text = '100';
            app.Label_6 = uilabel(app.GUI);
            app.Label_6.Text = '154886';
            app.Label_8 = uilabel(app.GUI);
            app.Label_8.Text = '853';
            app.Label_10 = uilabel(app.GUI);
            app.Label_10.Text = '186';
            app.Label_12 = uilabel(app.GUI);
            app.Label_12.Text = '5400';
            app.MinLabel = uilabel(app.GUI);
            app.MinLabel.Text = 'Min';
            app.MaxLabel = uilabel(app.GUI);
            app.MaxLabel.Text = 'Max';
            app.Button = uibutton(app.GUI, 'push');
            app.Button.Icon = 'Schema.png';
            app.Button.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = CFST_AxialCapacityPrediction

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GUI)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GUI)
        end
    end
end